classdef ECGUIApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ECGUI                        matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        RootpathMenu                 matlab.ui.container.Menu
        Menu                         matlab.ui.container.Menu
        EditMenu                     matlab.ui.container.Menu
        SaveMenu                     matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        LowfrequencyEditFieldLabel   matlab.ui.control.Label
        LowfrequencyEditField        matlab.ui.control.NumericEditField
        OrderEditFieldLabel          matlab.ui.control.Label
        OrderEditField               matlab.ui.control.NumericEditField
        IDEditFieldLabel             matlab.ui.control.Label
        IDEditField                  matlab.ui.control.NumericEditField
        ShowButton                   matlab.ui.control.Button
        DesignfilterButton           matlab.ui.control.Button
        HighfrequencyEditFieldLabel  matlab.ui.control.Label
        HighfrequencyEditField       matlab.ui.control.NumericEditField
        FolderaddressEditFieldLabel  matlab.ui.control.Label
        FolderaddressEditField       matlab.ui.control.EditField
        BandpassFilterdetailsLabel   matlab.ui.control.Label
        SamplerateEditFieldLabel     matlab.ui.control.Label
        SamplerateEditField          matlab.ui.control.NumericEditField
        LeadButtonGroup              matlab.ui.container.ButtonGroup
        IButton                      matlab.ui.control.RadioButton
        IIButton                     matlab.ui.control.RadioButton
        IIIButton                    matlab.ui.control.RadioButton
        aVRButton                    matlab.ui.control.RadioButton
        aVLButton                    matlab.ui.control.RadioButton
        aVFButton                    matlab.ui.control.RadioButton
        V1Button                     matlab.ui.control.RadioButton
        V2Button                     matlab.ui.control.RadioButton
        V3Button                     matlab.ui.control.RadioButton
        V4Button                     matlab.ui.control.RadioButton
        V5Button                     matlab.ui.control.RadioButton
        V6Button                     matlab.ui.control.RadioButton
        t0EditFieldLabel             matlab.ui.control.Label
        t0EditField                  matlab.ui.control.NumericEditField
        t1EditFieldLabel             matlab.ui.control.Label
        t1EditField                  matlab.ui.control.NumericEditField
        HTML                         matlab.ui.control.HTML
        HTML2                        matlab.ui.control.HTML
        RightPanel                   matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes1                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
%         bp1 = designfilt('bandpassiir', 'FilterOrder', app.OrderEditField, ...
%             'HalfPowerFrequency1', app.LowfrequencyEditField.Value, 'HalfPowerFrequency2', ...
%             app.HighfrequencyEditField.Value, ...
%             'SampleRate', app.SamplerateEditField);
        bp1 = designfilt('bandpassiir', 'FilterOrder', 2, ...
            'HalfPowerFrequency1', 1, 'HalfPowerFrequency2', ...
            249, ...
            'SampleRate', 500);
%         leads = ['I'; 'II'; 'III'; 'aVR'; 'aVL'; 'aVF'; 'V1'; 'V2'; 'V3'; 'V4'; 'V5'; 'V6'];
    end
    
    methods (Access = private)
        
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ECGUI.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {613, 613};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {196, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
     
            fname = [app.FolderaddressEditField.Value, '\A', num2str(app.IDEditField.Value)];
            try
                fnames = dir(app.FolderaddressEditField.Value);
                app.HTML2.HTMLSource = ['IDs: ', fnames(3).name(2:end-4), ' to ', fnames(end).name(2:end-4)];
            catch
                app.HTML2.HTMLSource = 'No such folder, check folders';
            end
%             disp(fnames(3).name);
            try
                gd = load([fname, '.mat']);
                c = fopen([fname, '.hea']);
                c = textscan(c, '%s', 'delimiter', '\n', 'whitespace', '');
                
                try
                    valf = gd.val(:, floor(app.t0EditField.Value/2)+1:ceil(app.t1EditField.Value/2)+1);
                    wins = floor((app.t1EditField.Value-app.t0EditField.Value)/2);
                    ncov = floor((app.t1EditField.Value-app.t0EditField.Value - 200)/2);
                    fwins = 500;
                    
                catch
                    valf = gd.val(:, :);
                    chtml = 'Wrong time interval, check time limits';
                    app.HTML.HTMLSource = ['<p style="font-family:sans-serif;font-size:140%">', chtml, ' </p>'];
                    wins = size(valf, 2);
                    ncov = size(valf, 2) - 10;
                    fwins = 500;
                    pause(2);
                end
                c = c{1, 1};
                chtml = '';
                for i = [1, 15, 16]
                    chtml = [chtml, '<br>', c{i, 1}];
                end
                app.HTML.HTMLSource = ['<p style="font-family:sans-serif;font-size:100%">', chtml, ' </p>'];
                
            catch
                valf = zeros(12, ceil(app.t1EditField.Value/2)-floor(app.t0EditField.Value/2));
                chtml = 'Wrong folder or file, check folder path';
                app.HTML.HTMLSource = ['<p style="font-family:sans-serif;font-size:150%">', chtml, ' </p>'];
                wins = size(valf, 2);
                ncov = size(valf, 2) - 10;
                fwins = 500;
            end
            
            if isequal('I', app.LeadButtonGroup.SelectedObject.Text)
                k = 1;
            elseif isequal('II', app.LeadButtonGroup.SelectedObject.Text)
                k = 2;
            elseif isequal('III', app.LeadButtonGroup.SelectedObject.Text)
                k = 3;
            elseif isequal('aVR', app.LeadButtonGroup.SelectedObject.Text)
                k = 4;
            elseif isequal('aVL', app.LeadButtonGroup.SelectedObject.Text)
                k = 5;
            elseif isequal('aVF', app.LeadButtonGroup.SelectedObject.Text)
                k = 6;
            elseif isequal('V1', app.LeadButtonGroup.SelectedObject.Text)
                k = 7;
            elseif isequal('V2', app.LeadButtonGroup.SelectedObject.Text)
                k = 8;
            elseif isequal('V3', app.LeadButtonGroup.SelectedObject.Text)
                k = 9;
            elseif isequal('V4', app.LeadButtonGroup.SelectedObject.Text)
                k = 10;
            elseif isequal('V5', app.LeadButtonGroup.SelectedObject.Text)
                k = 11;
            elseif isequal('V6', app.LeadButtonGroup.SelectedObject.Text)
                k = 12;
            end
           
            Ts = (0:size(valf, 2) - 1)/app.SamplerateEditField.Value;     
            plot(app.UIAxes1, Ts, filter(app.bp1, valf(k, :)));
            grid(app.UIAxes1, 'on');
            
            title(app.UIAxes1, app.LeadButtonGroup.SelectedObject.Text);

            [s, w, t] = spectrogram(filter(app.bp1, valf(k, :)), wins, ncov, fwins, app.SamplerateEditField.Value, 'yaxis', 'Minthreshold', -10);
            ts = linspace(app.t0EditField.Value, app.t1EditField.Value, size(s, 1));
            s = abs(s);
            
            imagesc(app.UIAxes2, ts, w, s);
            xlim(app.UIAxes2, [min(min(ts)), max(ts)]);
            ylim(app.UIAxes2, [0, 250]);
            
            xlabel(app.UIAxes2, 'Time');
            ylabel(app.UIAxes2, 'Frequency');
            colorbar(app.UIAxes2);
            
        end

        % Button pushed function: DesignfilterButton
        function DesignfilterButtonPushed(app, event)
            
            app.bp1 = designfilt('bandpassiir', 'FilterOrder', app.OrderEditField.Value, ...
                'HalfPowerFrequency1', app.LowfrequencyEditField.Value, 'HalfPowerFrequency2', ...
                app.HighfrequencyEditField.Value, ...
                'SampleRate', app.SamplerateEditField.Value);
            
            [h, w] = freqz(app.bp1);
            yyaxis(app.UIAxes, "left");
            plot(app.UIAxes, app.SamplerateEditField.Value*w/(2*pi), 20*log10(abs(h)));
            ylabel(app.UIAxes, 'Gain');
            xlabel(app.UIAxes, 'Frequency');
            
            yyaxis(app.UIAxes, "right");
            plot(app.UIAxes, app.SamplerateEditField.Value*w/(2*pi), angle(h)*180/pi);
            ylabel(app.UIAxes, 'Phase');
            grid(app.UIAxes, 'on');
            legend(app.UIAxes, 'Gain (dB)', 'Phase', 'Location',"south");

        end

        % Menu selected function: Menu
        function MenuSelected(app, event)
            
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            app.HTML.HTMLSource = 'Cannot save in your system!';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ECGUI = uifigure('Visible', 'off');
            app.ECGUI.Name = 'ECGUI';
            app.ECGUI.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.ECGUI);
            app.FileMenu.Text = 'File';
            app.RootpathMenu = uimenu(app.FileMenu);
            app.RootpathMenu.Text = 'Root path';
            app.Menu = uimenu(app.RootpathMenu);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Text = 'Menu';
            app.EditMenu = uimenu(app.ECGUI);
            app.EditMenu.Text = 'Edit';
            app.SaveMenu = uimenu(app.EditMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.GridLayout = uigridlayout(app.ECGUI);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LowfrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.LowfrequencyEditFieldLabel.Text = 'Low frequency';
            app.LowfrequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LowfrequencyEditField.Limits = [0 249];
            app.LowfrequencyEditField.ValueDisplayFormat = '%8.f Hz';
            app.LowfrequencyEditField.Value = 50;
            app.OrderEditFieldLabel = uilabel(app.LeftPanel);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.LeftPanel, 'numeric');
            app.OrderEditField.Limits = [1 100];
            app.OrderEditField.ValueDisplayFormat = '%d';
            app.OrderEditField.Value = 2;
            app.IDEditFieldLabel = uilabel(app.LeftPanel);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.LeftPanel, 'numeric');
            app.IDEditField.Limits = [1 10000];
            app.IDEditField.ValueDisplayFormat = '%.0f';
            app.IDEditField.Value = 5000;
            app.ShowButton = uibutton(app.LeftPanel, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.DesignfilterButton = uibutton(app.LeftPanel, 'push');
            app.DesignfilterButton.ButtonPushedFcn = createCallbackFcn(app, @DesignfilterButtonPushed, true);
            app.DesignfilterButton.Text = 'Design filter';
            app.HighfrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.HighfrequencyEditFieldLabel.Text = 'High frequency';
            app.HighfrequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.HighfrequencyEditField.Limits = [1 250];
            app.HighfrequencyEditField.ValueDisplayFormat = '%8.f Hz';
            app.HighfrequencyEditField.Value = 200;
            app.FolderaddressEditFieldLabel = uilabel(app.LeftPanel);
            app.FolderaddressEditFieldLabel.Text = 'Folder address';
            app.FolderaddressEditField = uieditfield(app.LeftPanel, 'text');
            app.FolderaddressEditField.Value = 'project data assignment\Nejat';
            app.BandpassFilterdetailsLabel = uilabel(app.LeftPanel);
            app.BandpassFilterdetailsLabel.Text = 'Bandpass Filter details';
            app.SamplerateEditFieldLabel = uilabel(app.LeftPanel);
            app.SamplerateEditFieldLabel.Text = 'Sample rate';
            app.SamplerateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SamplerateEditField.Limits = [1 1000];
            app.SamplerateEditField.Value = 500;
            app.LeadButtonGroup = uibuttongroup(app.LeftPanel);
            app.LeadButtonGroup.Title = 'Lead';
            app.IButton = uiradiobutton(app.LeadButtonGroup);
            app.IButton.Text = 'I';
            app.IButton.Value = true;
            app.IIButton = uiradiobutton(app.LeadButtonGroup);
            app.IIButton.Text = 'II';
            app.IIIButton = uiradiobutton(app.LeadButtonGroup);
            app.IIIButton.Text = 'III';
            app.aVRButton = uiradiobutton(app.LeadButtonGroup);
            app.aVRButton.Text = 'aVR';
            app.aVLButton = uiradiobutton(app.LeadButtonGroup);
            app.aVLButton.Text = 'aVL';
            app.aVFButton = uiradiobutton(app.LeadButtonGroup);
            app.aVFButton.Text = 'aVF';
            app.V1Button = uiradiobutton(app.LeadButtonGroup);
            app.V1Button.Text = 'V1';
            app.V2Button = uiradiobutton(app.LeadButtonGroup);
            app.V2Button.Text = 'V2';
            app.V3Button = uiradiobutton(app.LeadButtonGroup);
            app.V3Button.Text = 'V3';
            app.V4Button = uiradiobutton(app.LeadButtonGroup);
            app.V4Button.Text = 'V4';
            app.V5Button = uiradiobutton(app.LeadButtonGroup);
            app.V5Button.Text = 'V5';
            app.V6Button = uiradiobutton(app.LeadButtonGroup);
            app.V6Button.Text = 'V6';
            app.t0EditFieldLabel = uilabel(app.LeftPanel);
            app.t0EditFieldLabel.Text = 't0';
            app.t0EditField = uieditfield(app.LeftPanel, 'numeric');
            app.t0EditField.ValueDisplayFormat = '%11.4g ms';
            app.t1EditFieldLabel = uilabel(app.LeftPanel);
            app.t1EditFieldLabel.Text = 't1';
            app.t1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.t1EditField.ValueDisplayFormat = '%11.4g ms';
            app.t1EditField.Value = 1000;
            app.HTML = uihtml(app.LeftPanel);
            app.HTML2 = uihtml(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Filter response')
            xlabel(app.UIAxes, 'Frequency')
            ylabel(app.UIAxes, 'Gain')
            app.UIAxes1 = uiaxes(app.RightPanel);
            title(app.UIAxes1, 'Signal')
            xlabel(app.UIAxes1, 'Time (t)')
            ylabel(app.UIAxes1, {'Amplitude'; '(mV)'})
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Spectrum')
            xlabel(app.UIAxes2, 'Time (t)')
            ylabel(app.UIAxes2, 'Frequencies')
            % Show the figure after all components are created
            app.ECGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ECGUIApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ECGUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ECGUI)
        end
    end
end